classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        StanPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RobotLamp                      matlab.ui.control.Lamp
        RobotLampLabel                 matlab.ui.control.Label
        KameraLamp                     matlab.ui.control.Lamp
        KameraLampLabel                matlab.ui.control.Label
        loadedModelLabel               matlab.ui.control.Label
        WczytanymodelLabel             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SterowanieTab                  matlab.ui.container.Tab
        StartButton                    matlab.ui.control.StateButton
        ModelTab                       matlab.ui.container.Tab
        ListawytrenowanychmodeliLabel  matlab.ui.control.Label
        WczytajmodelButton             matlab.ui.control.Button
        ListamodeliListBox             matlab.ui.control.ListBox
        ListamodeliListBoxLabel        matlab.ui.control.Label
        PoczenieTab                    matlab.ui.container.Tab
        baudrateEditField              matlab.ui.control.EditField
        baudrateEditFieldLabel         matlab.ui.control.Label
        PortcomDropDown                matlab.ui.control.DropDown
        PortcomDropDownLabel           matlab.ui.control.Label
        portEditField                  matlab.ui.control.EditField
        portEditFieldLabel             matlab.ui.control.Label
        adresipEditField               matlab.ui.control.EditField
        adresipEditFieldLabel          matlab.ui.control.Label
        PoczButton                     matlab.ui.control.Button
        PoczzkamerButton               matlab.ui.control.Button
        ReczneTab                      matlab.ui.container.Tab
        robotManualStepSlider          matlab.ui.control.Slider
        KrokmmSliderLabel              matlab.ui.control.Label
        backwardBtn                    matlab.ui.control.Button
        rightBtn                       matlab.ui.control.Button
        leftBtn                        matlab.ui.control.Button
        ForwardBtn                     matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
         modelsDir      = "models";
         lampOffColor   = [0.85,0.33,0.10];
         lampOnColor    = [0.39,0.83,0.07];
         
         camIsConnected = false;
         cam            = 0;

         loopTimer      = timer('Period', 0.2, ...
                                'ExecutionMode', 'fixedSpacing');
         updateControllsTimer = timer('Period', 0.5, ...
                                'ExecutionMode', 'fixedSpacing');
         data = [];

         robotSerialPort = 0;
         robotIsConnected = false;
    end
    
    methods (Access = private)
        
        function res = connectToRobot(app, serialPort, baudRate)
            try
                app.robotSerialPort = serialport(serialPort, baudRate);
            catch ex
                msgbox(ex.message, "Błąd!", "error");
                app.robotIsConnected = false;
                return;
            end
            app.robotIsConnected = true;
        end

        function [response] = sendGCodeToRobot(app, command, showDialog)
            response = '';
            
            if ~app.robotIsConnected
                if  ~exist(showDialog, "var") || showDialog
                    msgbox("Nie polaczono z robotem!", "Błąd!!", "error");
                end
                return;
            end

            try
                response = writeread(app.robotSerialPort, command);
            catch ex
                app.robotIsConnected = false;

                if  ~exist(showDialog, "var") || showDialog
                    msgbox(ex.message, "Błąd!!", "error");
                end
                return; 
            end
            fprintf("[SEND GCODE] cmd=> %s, res => %s", command, response);
        end
        function updateControllsTimerFcn(app, obj, ev)
            % update controls 
            if app.robotIsConnected
                app.RobotLamp.Color = app.lampOnColor;
                app.RobotLamp.Enable = "on";
            else 
                app.RobotLamp.Color = app.lampOffColor;
                app.RobotLamp.Enable = "off";
            end

            if app.camIsConnected
                app.KameraLamp.Color = app.lampOnColor;
                app.KameraLamp.Enable = "on";
            else 
                app.KameraLamp.Color = app.lampOffColor;
                app.KameraLamp.Enable = "off";
            end
        end

        function controlLoop(app, obj, ev)
            if app.cam == 0
                msgbox("Blad podczas pobierania obrazu!!", "Błąd!", "error");
                return 
            end

            frame = (snapshot(app.cam));
            frame = imresize(frame, app.data.inputSize);

            [bboxes, scores, labels] = detect(app.data.detector, frame);
            if isempty(labels) == 0
                frame = insertObjectAnnotation(frame,"rectangle",bboxes,scores);    
            end
            disp(scores);
            app.Image.ImageSource = frame;
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loopTimer.TimerFcn = @app.controlLoop;
            app.updateControllsTimer.TimerFcn = @app.updateControllsTimerFcn;

            stop(app.loopTimer);
            start(app.updateControllsTimer);

            % pobranie plikow z wyternowanymi sieciami
            files = dir(fullfile(pwd, app.modelsDir, "*.mat"));
            app.ListamodeliListBox.Items = {files.name}';

            % pobranie dostepnych portow
            ports = serialportlist("available");
            app.PortcomDropDown.Items = ports;
        end

        % Button pushed function: WczytajmodelButton
        function WczytajmodelButtonPushed(app, event)
            fileToLoad = app.ListamodeliListBox.Value;
            selectedFileName = fullfile(app.modelsDir, fileToLoad);
      
            app.data = load(selectedFileName);
            app.loadedModelLabel.Text = fileToLoad;
            app.loadedModelLabel.BackgroundColor = [0.0 1.0 0.0];
            app.WczytanymodelLabel.BackgroundColor = [0.0 1.0 0.0];
        end

        % Clicked callback: PortcomDropDown
        function PortcomDropDownClicked(app, event)
            
        end

        % Drop down opening function: PortcomDropDown
        function PortcomDropDownOpening(app, event)
            %pobranie dostepnych portow
            ports = serialportlist("available");
            app.PortcomDropDown.Items = ports; 
        end

        % Button pushed function: PoczzkamerButton
        function PoczzkamerButtonPushed(app, event)
            try
                app.cam = ipcam(sprintf("http://%s:%s/video",app.adresipEditField.Value ,app.portEditField.Value), 'Timeout',2);
                img = snapshot(app.cam);
                app.Image.ImageSource = img;
            catch ex
                msgbox(ex.message, "Błąd!", "error");
                disp(ex);
                app.camIsConnected = false;
                return;
            end

            app.camIsConnected = true;
            start(app.loopTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.loopTimer ~= 0
                if get(app.loopTimer, "Running") == "On"
                    stop(app.loopTimer)
                end
                delete(app.loopTimer);
            end
            stop(app.updateControllsTimer);
            delete(app.updateControllsTimer);
            delete(app);
        end

        % Button pushed function: PoczButton
        function PoczButtonPushed(app, event)
               if isempty(app.baudrateEditField.Value) || isempty(app.PortcomDropDown.Value) || isinteger(app.baudrateEditField.Value)
                   msgbox("Wybierz port i podaj predkosc transmisji!", "Błąd!", "error")
                   return
               end

               % connect with robot 
               app.connectToRobot(app, app.PortcomDropDown.Value, int(app.baudrateEditField.Value));

        end

        % Button pushed function: ForwardBtn, backwardBtn, leftBtn, 
        % ...and 1 other component
        function ForwardBtnPushed(app, event)
            tag = get(event.Source, "Tag");
            stepMM = round(app.robotManualStepSlider.Value);
            cmdToSend = "";

            switch tag
                case "forward"
                    cmdToSend = sprintf("G1 X%i", stepMM);
                case "backward"
                    cmdToSend = sprintf("G1 X-%i", stepMM);
                case "left"
                    cmdToSend = sprintf("G1 Y%i", stepMM);
                case "right"
                    cmdToSend = sprintf("G1 Y-%i", stepMM);
            end
             
             res = app.sendGCodeToRobot(app, cmdToSend);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x', 78};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SterowanieTab = uitab(app.TabGroup);
            app.SterowanieTab.Title = 'Sterowanie';
            app.StartButton = uibutton(app.SterowanieTab, 'state');
            app.StartButton.Text = 'Start';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ListamodeliListBoxLabel = uilabel(app.ModelTab);
            app.ListamodeliListBoxLabel.Text = 'Lista modeli';
            app.ListamodeliListBox = uilistbox(app.ModelTab);
            app.WczytajmodelButton = uibutton(app.ModelTab, 'push');
            app.WczytajmodelButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajmodelButtonPushed, true);
            app.WczytajmodelButton.Text = 'Wczytaj model';
            app.ListawytrenowanychmodeliLabel = uilabel(app.ModelTab);
            app.ListawytrenowanychmodeliLabel.Text = 'Lista wytrenowanych modeli';
            app.PoczenieTab = uitab(app.TabGroup);
            app.PoczenieTab.Title = 'Połączenie';
            app.PoczzkamerButton = uibutton(app.PoczenieTab, 'push');
            app.PoczzkamerButton.ButtonPushedFcn = createCallbackFcn(app, @PoczzkamerButtonPushed, true);
            app.PoczzkamerButton.Text = 'Połącz z kamerą';
            app.PoczButton = uibutton(app.PoczenieTab, 'push');
            app.PoczButton.ButtonPushedFcn = createCallbackFcn(app, @PoczButtonPushed, true);
            app.PoczButton.Text = 'Połącz';
            app.adresipEditFieldLabel = uilabel(app.PoczenieTab);
            app.adresipEditFieldLabel.Text = 'adres ip';
            app.adresipEditField = uieditfield(app.PoczenieTab, 'text');
            app.adresipEditField.Value = '192.168.1.115';
            app.portEditFieldLabel = uilabel(app.PoczenieTab);
            app.portEditFieldLabel.Text = 'port';
            app.portEditField = uieditfield(app.PoczenieTab, 'text');
            app.portEditField.Value = '8080';
            app.PortcomDropDownLabel = uilabel(app.PoczenieTab);
            app.PortcomDropDownLabel.Text = 'Port com';
            app.PortcomDropDown = uidropdown(app.PoczenieTab);
            app.PortcomDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortcomDropDownOpening, true);
            app.PortcomDropDown.ClickedFcn = createCallbackFcn(app, @PortcomDropDownClicked, true);
            app.baudrateEditFieldLabel = uilabel(app.PoczenieTab);
            app.baudrateEditFieldLabel.Text = 'baudrate';
            app.baudrateEditField = uieditfield(app.PoczenieTab, 'text');
            app.baudrateEditField.Value = '9600';
            app.ReczneTab = uitab(app.TabGroup);
            app.ReczneTab.Title = 'Reczne';
            app.ForwardBtn = uibutton(app.ReczneTab, 'push');
            app.ForwardBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.ForwardBtn.Tag = 'forward';
            app.ForwardBtn.Text = '⏫';
            app.leftBtn = uibutton(app.ReczneTab, 'push');
            app.leftBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.leftBtn.Tag = 'left';
            app.leftBtn.Text = '⏪';
            app.rightBtn = uibutton(app.ReczneTab, 'push');
            app.rightBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.rightBtn.Tag = 'right';
            app.rightBtn.Text = '⏩';
            app.backwardBtn = uibutton(app.ReczneTab, 'push');
            app.backwardBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.backwardBtn.Tag = 'backward';
            app.backwardBtn.Text = '⏬';
            app.KrokmmSliderLabel = uilabel(app.ReczneTab);
            app.KrokmmSliderLabel.Text = 'Krok[mm]';
            app.robotManualStepSlider = uislider(app.ReczneTab);
            app.robotManualStepSlider.Limits = [10 100];
            app.robotManualStepSlider.Value = 10;
            app.StanPanel = uipanel(app.LeftPanel);
            app.StanPanel.Title = 'Stan';
            app.GridLayout2 = uigridlayout(app.StanPanel);
            app.WczytanymodelLabel = uilabel(app.GridLayout2);
            app.WczytanymodelLabel.Text = 'Wczytany model';
            app.loadedModelLabel = uilabel(app.GridLayout2);
            app.loadedModelLabel.Text = '';
            app.KameraLampLabel = uilabel(app.GridLayout2);
            app.KameraLampLabel.Text = 'Kamera';
            app.KameraLamp = uilamp(app.GridLayout2);
            app.RobotLampLabel = uilabel(app.GridLayout2);
            app.RobotLampLabel.Text = 'Robot';
            app.RobotLamp = uilamp(app.GridLayout2);
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'imgNotFound.png');
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end