classdef appV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboControllerUIFigure         matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        WczytajmodelpoczkamereirobotaLabel  matlab.ui.control.Label
        StanPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        SterowanieLamp                 matlab.ui.control.Lamp
        SterowanieLampLabel            matlab.ui.control.Label
        StatuspolaczeniazrobotemLamp   matlab.ui.control.Lamp
        StatuspolaczeniazrobotemLampLabel  matlab.ui.control.Label
        StatuspoczeniazkamerLamp       matlab.ui.control.Lamp
        StatuspoczeniazkamerLampLabel  matlab.ui.control.Label
        loadedModelLabel               matlab.ui.control.Label
        WczytanymodelLabel             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        mainTab                        matlab.ui.container.Tab
        UstawieniaPanel                matlab.ui.container.Panel
        TrybledzeniaCheckBox           matlab.ui.control.CheckBox
        wykryteTextArea                matlab.ui.control.TextArea
        wykryteTextAreaLabel           matlab.ui.control.Label
        StartButton                    matlab.ui.control.StateButton
        CzasprzetwarzaniaEditField     matlab.ui.control.EditField
        CzasprzetwarzaniaEditFieldLabel  matlab.ui.control.Label
        KlasyDropDown                  matlab.ui.control.DropDown
        KlasyDropDownLabel             matlab.ui.control.Label
        ModelTab                       matlab.ui.container.Tab
        autoloadCheckBox               matlab.ui.control.CheckBox
        ListawytrenowanychmodeliLabel  matlab.ui.control.Label
        WczytajmodelButton             matlab.ui.control.Button
        ListamodeliListBox             matlab.ui.control.ListBox
        ListamodeliListBoxLabel        matlab.ui.control.Label
        PoczenieTab                    matlab.ui.container.Tab
        nastawaP2EditField             matlab.ui.control.EditField
        nastawaP2EditFieldLabel        matlab.ui.control.Label
        nastawaP1EditField             matlab.ui.control.EditField
        nastawaP1EditFieldLabel        matlab.ui.control.Label
        autoconnectCheckBox_2          matlab.ui.control.CheckBox
        autoconnectCheckBox            matlab.ui.control.CheckBox
        KameraDropDown                 matlab.ui.control.DropDown
        KameraDropDownLabel            matlab.ui.control.Label
        LogiTextArea                   matlab.ui.control.TextArea
        LogiTextAreaLabel              matlab.ui.control.Label
        WyslijButton                   matlab.ui.control.Button
        GCodeEditField                 matlab.ui.control.EditField
        GCodeEditFieldLabel            matlab.ui.control.Label
        baudrateEditField              matlab.ui.control.EditField
        baudrateEditFieldLabel         matlab.ui.control.Label
        PortcomDropDown                matlab.ui.control.DropDown
        PortcomDropDownLabel           matlab.ui.control.Label
        PoczButton                     matlab.ui.control.Button
        PoczzkamerButton               matlab.ui.control.Button
        SterowanieTab                  matlab.ui.container.Tab
        robotManualStepSpeed           matlab.ui.control.Slider
        SzybkoLabel                    matlab.ui.control.Label
        robotManualStepSlider          matlab.ui.control.Slider
        KrokmmSliderLabel              matlab.ui.control.Label
        backwardBtn                    matlab.ui.control.Button
        rightBtn                       matlab.ui.control.Button
        leftBtn                        matlab.ui.control.Button
        ForwardBtn                     matlab.ui.control.Button
        InformacjeTab                  matlab.ui.container.Tab
        OpiekunpracydrhabinKrzysztofWiktorowiczprofPRzLabel  matlab.ui.control.Label
        ProgramjestczciprojektuinynierskiegoLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
         modelsDir      = "models";
         lampOffColor   = [0.85,0.33,0.10];
         lampOnColor    = [0.39,0.83,0.07];
         
         camIsConnected = false;
         cam            = 0;

         loopTimer              = timer('Period', 0.1, ...
                                'ExecutionMode', 'fixedSpacing', 'StartDelay', 0.1);
         updateControllsTimer   = timer('Period', 0.5, ...
                                'ExecutionMode', 'fixedSpacing');
         updateCameraView       = timer('Period', 0.05, ...
                                'ExecutionMode', 'fixedSpacing');

         robot = robotController();
         data = [];
         selectedPosFromYOLO = [];
        
         isYOLORunning = false;

         settings = [];
         
    end
    
    methods (Access = public)
         function updateControllsTimerFcn(app, obj, ev)
            % update controls 
            if app.robot.isConnected == 1
                app.StatuspolaczeniazrobotemLamp.Color = app.lampOnColor;
                app.StatuspolaczeniazrobotemLamp.Enable = "on";
            else 
                app.StatuspolaczeniazrobotemLamp.Color = app.lampOffColor;
                app.StatuspolaczeniazrobotemLamp.Enable = "off";
            end

            if app.camIsConnected
                app.StatuspoczeniazkamerLamp.Color = app.lampOnColor;
                app.StatuspoczeniazkamerLamp.Enable = "on";
            else 
                app.StatuspoczeniazkamerLamp.Color = app.lampOffColor;
                app.StatuspoczeniazkamerLamp.Enable = "off";
            end

            if app.isYOLORunning
                app.SterowanieLamp.Color = app.lampOnColor;
                app.SterowanieLamp.Enable = "on";
            else 
                app.SterowanieLamp.Color = app.lampOffColor;
                app.SterowanieLamp.Enable = "off";
            end
            
            % aktualizacja panelu do sterowania algorytmem
            if app.camIsConnected && ~isempty(app.data)  && true %app.robotIsConnected
                app.UstawieniaPanel.Enable = true;
                app.KlasyDropDown.Items = app.data.classes;
                app.WczytajmodelpoczkamereirobotaLabel.Visible = false;
            else 
                app.WczytajmodelpoczkamereirobotaLabel.Visible = true;
                app.UstawieniaPanel.Enable = false;
                app.isYOLORunning = false;
            end
        end
        function cameraLoop(app, obj, ev)
            if app.isYOLORunning
                return
            end
            if ~app.camIsConnected
                return
            end
            imSize = [224, 224];
            if exist("app.data.inputSize", "var") == 1
                imSize = app.data.inputSize;
            end

            frame = snapshot(app.cam);
            frame = imresize(frame, imSize);
            frame = insertShape(frame, "rectangle", [imSize(1)/2 - 15, imSize(2)/2 - 15, 30, 30], "Color","red");

            app.Image.ImageSource = frame;
        end
        function connectWithCam(app, cam)
            try
                app.cam = webcam(cam);
                img = snapshot(app.cam);
                app.Image.ImageSource = img;
            catch ex
                msgbox(ex.message, "Błąd!", "error");
                disp(ex);
                app.camIsConnected = false;
                return;
            end
            app.camIsConnected = true;
        end
        function  YOLOStop(app)
            stop(app.loopTimer);
            app.StartButton.Text = "Start";
            app.isYOLORunning = false;
            app.TrybledzeniaCheckBox.Enable = true;    
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loopTimer.TimerFcn = {@mainLoop, app};
            app.updateControllsTimer.TimerFcn = @app.updateControllsTimerFcn;
            app.updateCameraView.TimerFcn = @app.cameraLoop;

            start(app.updateControllsTimer);
            start(app.updateCameraView);

            % pobranie plikow z wyternowanymi sieciami
            files = dir(fullfile(pwd, app.modelsDir, "*.mat"));
            app.ListamodeliListBox.Items = {files.name}';

            app.settings = load("settings.mat");
            app.autoloadCheckBox.Value = app.settings.modelAutoLoad;
            if(sum(strcmp(string(app.ListamodeliListBox.Items), app.settings.modelSelected)) == 1 && app.settings.modelAutoLoad == true)
                %auto load model
                app.ListamodeliListBox.Value = app.settings.modelSelected;
                selectedFileName = fullfile(app.modelsDir, app.settings.modelSelected);
                app.loadedModelLabel.Text = app.settings.modelSelected;
                app.data = load(selectedFileName);
                
                app.KlasyDropDown.Items                 = app.data.classes;
                app.loadedModelLabel.BackgroundColor    = [0.0 1.0 0.0];
                app.WczytanymodelLabel.BackgroundColor  = [0.0 1.0 0.0];
            elseif (sum(strcmp(string(app.ListamodeliListBox.Items), app.settings.modelSelected)) == 1)
                app.ListamodeliListBox.Value = app.settings.modelSelected;
            end

            % pobranie dostepnych portow
            ports = serialportlist("available");
            app.PortcomDropDown.Items = ports;
            app.autoconnectCheckBox.Value = app.settings.robotAutoConnect;
            if(sum(strcmp(string(ports), app.settings.robotPort)) == 1 ...
                    && ~isempty(str2num(app.settings.robotBaudRate)))
                %auto connect
                app.PortcomDropDown.Value       = app.settings.robotPort;
                app.baudrateEditField.Value     = app.settings.robotBaudRate;
                if app.settings.robotAutoConnect == true
                    [app.robot res] = app.robot.connect(app.PortcomDropDown.Value, str2double(app.baudrateEditField.Value));
                end
            end

            
            % pobranie kamer 
            cams = webcamlist();
            app.KameraDropDown.Items = cams; 
            app.autoconnectCheckBox_2.Value = app.settings.camAutoConnect;
            if(sum(strcmp(string(cams), app.settings.camSelected)) == 1 ...
                    && app.settings.camAutoConnect == true)
                %auto connect
                app.KameraDropDown.Value = app.settings.camSelected;
                app.connectWithCam(app.settings.camSelected);
            end

            % wczytanie nastaw dla trybu manualnego
            app.nastawaP1EditField.Value = num2str(app.settings.robotManualModePixToImg1);
            app.nastawaP2EditField.Value = num2str(app.settings.robotManualModePixToImg2);
        end

        % Button pushed function: WczytajmodelButton
        function WczytajmodelButtonPushed(app, event)
            fileToLoad = app.ListamodeliListBox.Value;
            selectedFileName = fullfile(app.modelsDir, fileToLoad);
      
            app.data = load(selectedFileName);
            app.loadedModelLabel.Text = fileToLoad;
            
            app.KlasyDropDown.Items = app.data.classes;

            app.loadedModelLabel.BackgroundColor = [0.0 1.0 0.0];
            app.WczytanymodelLabel.BackgroundColor = [0.0 1.0 0.0];
        end

        % Drop down opening function: PortcomDropDown
        function PortcomDropDownOpening(app, event)
            %pobranie dostepnych portow
            ports = serialportlist("available");
            app.PortcomDropDown.Items = ports; 
        end

        % Button pushed function: PoczzkamerButton
        function PoczzkamerButtonPushed(app, event)
            app.connectWithCam(app.KameraDropDown.Value);
        end

        % Close request function: RoboControllerUIFigure
        function RoboControllerUIFigureCloseRequest(app, event)
            set = {};
            set.modelAutoLoad = app.autoloadCheckBox.Value;
            set.modelSelected = app.ListamodeliListBox.Value;
            
            set.camAutoConnect = app.autoconnectCheckBox_2.Value;
            set.camSelected = app.KameraDropDown.Value;
            
            set.robotAutoConnect = app.autoconnectCheckBox.Value;
            set.robotBaudRate = app.baudrateEditField.Value;
            set.robotPort = app.PortcomDropDown.Value;

            set.robotManualModePixToImg1 = str2double(app.nastawaP1EditField.Value);
            set.robotManualModePixToImg2 = str2double(app.nastawaP2EditField.Value);
            save("settings.mat", '-struct', "set");
            
            if app.loopTimer ~= 0
                if get(app.loopTimer, "Running") == "On"
                    stop(app.loopTimer)
                end
                delete(app.loopTimer);
            end
            stop(app.updateControllsTimer);
            delete(app.updateControllsTimer);
            delete(app);
            
        end

        % Button pushed function: PoczButton
        function PoczButtonPushed(app, event)
               if isempty(app.baudrateEditField.Value) || isempty(app.PortcomDropDown.Value) || isinteger(app.baudrateEditField.Value)
                   msgbox("Wybierz port i podaj predkosc transmisji!", "Błąd!", "error")
                   return
               end

               % connect with robot 
               [app.robot res] = app.robot.connect(app.PortcomDropDown.Value, str2double(app.baudrateEditField.Value));

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RoboControllerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {650, 650};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {355, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ForwardBtn, backwardBtn, leftBtn, 
        % ...and 1 other component
        function ForwardBtnPushed(app, event)
            tag = get(event.Source, "Tag");
            stepMM = round(app.robotManualStepSlider.Value);
            speedMM = round(app.robotManualStepSpeed.Value);
            cmdToSend = "";

            switch tag
                case "forward"
                    cmdToSend = sprintf("G1 X%i F%i", stepMM, speedMM);
                case "backward"
                    cmdToSend = sprintf("G1 X-%i F%i", stepMM, speedMM);
                case "left"
                    cmdToSend = sprintf("G1 Y%i F%i", stepMM, speedMM);
                case "right"
                    cmdToSend = sprintf("G1 Y-%i F%i", stepMM, speedMM);
            end
            res = app.robot.sendGCodeToRobot(cmdToSend);
        end

        % Clicked callback: PortcomDropDown
        function PortcomDropDownClicked(app, event)
            
        end

        % Button pushed function: WyslijButton
        function WyslijButtonPushed(app, event)
            if ~app.robot.isConnected
                return
            end
            cmd = app.GCodeEditField.Value;
            if ~isempty(cmd)
                res = app.robot.sendGCodeToRobot(cmd);
                app.LogiTextArea.Value = [res; app.LogiTextArea.Value];
            end
        end

        % Drop down opening function: KameraDropDown
        function KameraDropDownOpening(app, event)
            cams = webcamlist();
            app.KameraDropDown.Items = cams;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.isYOLORunning
                stop(app.loopTimer);
                app.StartButton.Text = "Start";
                app.isYOLORunning = false;
                app.TrybledzeniaCheckBox.Enable = true;
            else 
                start(app.loopTimer);
                app.StartButton.Text = "Stop";
                app.isYOLORunning = true;
                app.selectedPosFromYOLO = [];
                app.TrybledzeniaCheckBox.Enable = false;
            end            
        end

        % Drop down opening function: KlasyDropDown
        function KlasyDropDownOpening(app, event)
            app.selectedPosFromYOLO = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RoboControllerUIFigure = uifigure('Visible', 'off');
            app.RoboControllerUIFigure.Name = 'RoboController';
            app.RoboControllerUIFigure.Icon = fullfile(pathToMLAPP, 'appV2_resources', 'icon_48.png');
            app.RoboControllerUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoboControllerUIFigureCloseRequest, true);
            app.RoboControllerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RoboControllerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.mainTab = uitab(app.TabGroup);
            app.mainTab.Title = 'main';
            app.UstawieniaPanel = uipanel(app.mainTab);
            app.UstawieniaPanel.Enable = 'off';
            app.UstawieniaPanel.Title = 'Ustawienia';
            app.KlasyDropDownLabel = uilabel(app.UstawieniaPanel);
            app.KlasyDropDownLabel.Text = 'Klasy';
            app.KlasyDropDown = uidropdown(app.UstawieniaPanel);
            app.KlasyDropDown.DropDownOpeningFcn = createCallbackFcn(app, @KlasyDropDownOpening, true);
            app.CzasprzetwarzaniaEditFieldLabel = uilabel(app.UstawieniaPanel);
            app.CzasprzetwarzaniaEditFieldLabel.Text = 'Czas przetwarzania:';
            app.CzasprzetwarzaniaEditField = uieditfield(app.UstawieniaPanel, 'text');
            app.CzasprzetwarzaniaEditField.Editable = 'off';
            app.StartButton = uibutton(app.UstawieniaPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.wykryteTextAreaLabel = uilabel(app.UstawieniaPanel);
            app.wykryteTextAreaLabel.Text = 'wykryte';
            app.wykryteTextArea = uitextarea(app.UstawieniaPanel);
            app.TrybledzeniaCheckBox = uicheckbox(app.UstawieniaPanel);
            app.TrybledzeniaCheckBox.Text = 'Tryb śledzenia';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ListamodeliListBoxLabel = uilabel(app.ModelTab);
            app.ListamodeliListBoxLabel.Text = 'Lista modeli';
            app.ListamodeliListBox = uilistbox(app.ModelTab);
            app.WczytajmodelButton = uibutton(app.ModelTab, 'push');
            app.WczytajmodelButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajmodelButtonPushed, true);
            app.WczytajmodelButton.Text = 'Wczytaj model';
            app.ListawytrenowanychmodeliLabel = uilabel(app.ModelTab);
            app.ListawytrenowanychmodeliLabel.Text = 'Lista wytrenowanych modeli';
            app.autoloadCheckBox = uicheckbox(app.ModelTab);
            app.autoloadCheckBox.Text = 'auto load';
            app.PoczenieTab = uitab(app.TabGroup);
            app.PoczenieTab.Title = 'Połączenie';
            app.PoczzkamerButton = uibutton(app.PoczenieTab, 'push');
            app.PoczzkamerButton.ButtonPushedFcn = createCallbackFcn(app, @PoczzkamerButtonPushed, true);
            app.PoczzkamerButton.Text = 'Połącz z kamerą';
            app.PoczButton = uibutton(app.PoczenieTab, 'push');
            app.PoczButton.ButtonPushedFcn = createCallbackFcn(app, @PoczButtonPushed, true);
            app.PoczButton.Text = 'Połącz';
            app.PortcomDropDownLabel = uilabel(app.PoczenieTab);
            app.PortcomDropDownLabel.Text = 'Port com';
            app.PortcomDropDown = uidropdown(app.PoczenieTab);
            app.PortcomDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortcomDropDownOpening, true);
            app.PortcomDropDown.ClickedFcn = createCallbackFcn(app, @PortcomDropDownClicked, true);
            app.baudrateEditFieldLabel = uilabel(app.PoczenieTab);
            app.baudrateEditFieldLabel.Text = 'baudrate';
            app.baudrateEditField = uieditfield(app.PoczenieTab, 'text');
            app.baudrateEditField.Value = '9600';
            app.GCodeEditFieldLabel = uilabel(app.PoczenieTab);
            app.GCodeEditFieldLabel.Text = 'GCode';
            app.GCodeEditField = uieditfield(app.PoczenieTab, 'text');
            app.WyslijButton = uibutton(app.PoczenieTab, 'push');
            app.WyslijButton.ButtonPushedFcn = createCallbackFcn(app, @WyslijButtonPushed, true);
            app.WyslijButton.Text = 'Wyslij';
            app.LogiTextAreaLabel = uilabel(app.PoczenieTab);
            app.LogiTextAreaLabel.Text = 'Logi';
            app.LogiTextArea = uitextarea(app.PoczenieTab);
            app.LogiTextArea.Editable = 'off';
            app.KameraDropDownLabel = uilabel(app.PoczenieTab);
            app.KameraDropDownLabel.Text = 'Kamera';
            app.KameraDropDown = uidropdown(app.PoczenieTab);
            app.KameraDropDown.DropDownOpeningFcn = createCallbackFcn(app, @KameraDropDownOpening, true);
            app.autoconnectCheckBox = uicheckbox(app.PoczenieTab);
            app.autoconnectCheckBox.Text = 'auto connect';
            app.autoconnectCheckBox_2 = uicheckbox(app.PoczenieTab);
            app.autoconnectCheckBox_2.Text = 'auto connect';
            app.nastawaP1EditFieldLabel = uilabel(app.PoczenieTab);
            app.nastawaP1EditFieldLabel.Text = 'nastawaP1';
            app.nastawaP1EditField = uieditfield(app.PoczenieTab, 'text');
            app.nastawaP2EditFieldLabel = uilabel(app.PoczenieTab);
            app.nastawaP2EditFieldLabel.Text = 'nastawaP2';
            app.nastawaP2EditField = uieditfield(app.PoczenieTab, 'text');
            app.SterowanieTab = uitab(app.TabGroup);
            app.SterowanieTab.Title = 'Sterowanie';
            app.ForwardBtn = uibutton(app.SterowanieTab, 'push');
            app.ForwardBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.ForwardBtn.Tag = 'forward';
            app.ForwardBtn.Text = '⏫';
            app.leftBtn = uibutton(app.SterowanieTab, 'push');
            app.leftBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.leftBtn.Tag = 'left';
            app.leftBtn.Text = '⏪';
            app.rightBtn = uibutton(app.SterowanieTab, 'push');
            app.rightBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.rightBtn.Tag = 'right';
            app.rightBtn.Text = '⏩';
            app.backwardBtn = uibutton(app.SterowanieTab, 'push');
            app.backwardBtn.ButtonPushedFcn = createCallbackFcn(app, @ForwardBtnPushed, true);
            app.backwardBtn.Tag = 'backward';
            app.backwardBtn.Text = '⏬';
            app.KrokmmSliderLabel = uilabel(app.SterowanieTab);
            app.KrokmmSliderLabel.Text = 'Krok[mm]';
            app.robotManualStepSlider = uislider(app.SterowanieTab);
            app.robotManualStepSlider.Limits = [10 100];
            app.robotManualStepSlider.Value = 10;
            app.SzybkoLabel = uilabel(app.SterowanieTab);
            app.SzybkoLabel.Text = 'Szybkość';
            app.robotManualStepSpeed = uislider(app.SterowanieTab);
            app.robotManualStepSpeed.Limits = [400 2000];
            app.robotManualStepSpeed.Value = 600;
            app.InformacjeTab = uitab(app.TabGroup);
            app.InformacjeTab.Title = 'Informacje';
            app.ProgramjestczciprojektuinynierskiegoLabel = uilabel(app.InformacjeTab);
            app.ProgramjestczciprojektuinynierskiegoLabel.Text = {'Program jest częścią projektu inżynierskiego pt "Śledzenie obiektów przez robota mobilnego z wykorzystaniem'; 'głębokiej sieci neuronowej".'};
            app.OpiekunpracydrhabinKrzysztofWiktorowiczprofPRzLabel = uilabel(app.InformacjeTab);
            app.OpiekunpracydrhabinKrzysztofWiktorowiczprofPRzLabel.Text = 'Opiekun pracy: dr.hab.inż Krzysztof Wiktorowicz prof. PRz';
            app.StanPanel = uipanel(app.LeftPanel);
            app.StanPanel.Title = 'Stan';
            app.GridLayout2 = uigridlayout(app.StanPanel);
            app.WczytanymodelLabel = uilabel(app.GridLayout2);
            app.WczytanymodelLabel.Text = 'Wczytany model';
            app.loadedModelLabel = uilabel(app.GridLayout2);
            app.loadedModelLabel.Text = '';
            app.StatuspoczeniazkamerLampLabel = uilabel(app.GridLayout2);
            app.StatuspoczeniazkamerLampLabel.Text = 'Status połączenia z kamerą';
            app.StatuspoczeniazkamerLamp = uilamp(app.GridLayout2);
            app.StatuspolaczeniazrobotemLampLabel = uilabel(app.GridLayout2);
            app.StatuspolaczeniazrobotemLampLabel.Text = 'Status polaczenia z robotem';
            app.StatuspolaczeniazrobotemLamp = uilamp(app.GridLayout2);
            app.SterowanieLampLabel = uilabel(app.GridLayout2);
            app.SterowanieLampLabel.Text = 'Sterowanie';
            app.SterowanieLamp = uilamp(app.GridLayout2);
            app.WczytajmodelpoczkamereirobotaLabel = uilabel(app.LeftPanel);
            app.WczytajmodelpoczkamereirobotaLabel.Text = 'Wczytaj model, połącz kamere i robota';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'camNotFound.png');
            % Show the figure after all components are created
            app.RoboControllerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboControllerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboControllerUIFigure)
        end
    end
end